classdef AnalysisMaskSelector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CreateAnalysisMaskUIFigure  matlab.ui.Figure
        RendererDropDown            matlab.ui.control.DropDown
        RendererDropDownLabel       matlab.ui.control.Label
        cutoffSlider                matlab.ui.control.Slider
        cutoffSliderLabel           matlab.ui.control.Label
        ThresholdEditField          matlab.ui.control.NumericEditField
        viewDropDown                matlab.ui.control.DropDown
        viewDropDownLabel           matlab.ui.control.Label
        thenLabel_2                 matlab.ui.control.Label
        thenLabel                   matlab.ui.control.Label
        closeEditField              matlab.ui.control.NumericEditField
        closeEditFieldLabel         matlab.ui.control.Label
        isosurfacePanel             matlab.ui.container.Panel
        DoneButton                  matlab.ui.control.StateButton
        ThresholdSlider             matlab.ui.control.Slider
        ThresholdSliderLabel        matlab.ui.control.Label
        UpdateButton                matlab.ui.control.StateButton
        dilateEditField             matlab.ui.control.NumericEditField
        dilateEditFieldLabel        matlab.ui.control.Label
        blurzEditField              matlab.ui.control.NumericEditField
        blurzEditFieldLabel         matlab.ui.control.Label
        blurxyEditField             matlab.ui.control.NumericEditField
        blurxyEditFieldLabel        matlab.ui.control.Label
        VolumeViewerButton          matlab.ui.control.StateButton
        OrthoViewPanel              matlab.ui.container.Panel
    end

    
    properties (Access = private)
        tm % TemplateMaker
        vs
        osv
        im
    end
    
    methods (Access = private)
        
        function makeImage(app)

            d = uiprogressdlg(app.CreateAnalysisMaskUIFigure, 'Title','Please Wait','Message', 'Updating');
            sigma = [app.blurxyEditField.Value app.blurxyEditField.Value app.blurzEditField.Value];
            app.tm.makeAnalysisMask(sigma, app.ThresholdEditField.Value, app.dilateEditField.Value, app.closeEditField.Value);
            d.close();


        end
        function updateDisplay(app)
            switch(lower(app.viewDropDown.Value))
                case 'original'
                    app.im = app.tm.template.F;
                case 'blurred'
                    app.im = app.tm.analysis_mask.blurred_im;
                case 'thresholded'
                    app.im = app.tm.analysis_mask.thresholded_mask.*app.tm.analysis_mask.blurred_im;
                case 'final'
                    app.im = app.tm.analysis_mask.mask.*app.tm.template.F;
                case 'mask'
                    app.im = double(app.tm.analysis_mask.mask);
                    
            end
            
            app.ThresholdSlider.Limits = [0 max(app.tm.analysis_mask.blurred_im, [], 'all')];

            try
                p = app.OrthoViewPanel.Position;
                app.OrthoViewPanel.delete();
                app.OrthoViewPanel = uipanel(app.CreateAnalysisMaskUIFigure);
                app.OrthoViewPanel.Position = p;          
                p = app.isosurfacePanel.Position;
                app.isosurfacePanel.delete();
                app.isosurfacePanel = uipanel(app.CreateAnalysisMaskUIFigure);
                app.isosurfacePanel.Position = p;
               
                cm = jet(256);
                cm(1,:) = 1;
                app.osv = orthosliceViewer(app.im, 'Parent',app.OrthoViewPanel, 'ScaleFactors', [app.tm.xybin app.tm.xybin app.tm.zbin], 'Colormap', cm);
               
                [hXY,hYZ,hXZ] = getAxesHandles(app.osv);
                disableDefaultInteractivity(hXY);
                disableDefaultInteractivity(hYZ);
                disableDefaultInteractivity(hXZ);
    
                app.vs = volshow(app.im, 'Parent',app.isosurfacePanel, 'Renderer', app.RendererDropDown.Value, ...
                    'BackgroundColor', 'w', 'ScaleFactors', [app.tm.xybin app.tm.xybin app.tm.zbin], 'Isovalue', app.cutoffSlider.Value,...
                    'Colormap', cm);
                 nz = max(1,ceil(app.cutoffSlider.Value*255));
    
                app.vs.Alphamap(1:nz) = 0;
                app.vs.Alphamap(nz:end) = linspace(0,1,length(app.vs.Alphamap(nz:end)));
            catch me
            end

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, template_maker)
            app.tm = template_maker;
            app.ThresholdSlider.Limits = [0 max(app.tm.template.F,[],'all')];
            try
                app.ThresholdSlider.Value = app.tm.analysis_mask.threshold;
                app.ThresholdEditField.Value = app.tm.analysis_mask.threshold;
                app.blurxyEditField.Value = app.tm.analysis_mask.sigma(1);
                app.blurzEditField.Value = app.tm.analysis_mask.sigma(end);
                app.dilateEditField.Value = app.tm.analysis_mask.dilate;
                app.closeEditField.Value = app.tm.analysis_mask.close;
            catch
                app.ThresholdSlider.Value = min(app.tm.template.F,[],'all');
                app.ThresholdEditField.Value = app.ThresholdSlider.Value;
            end
            app.makeImage();
            app.updateDisplay();


        end

        % Value changed function: UpdateButton, blurxyEditField, 
        % ...and 4 other components
        function UpdateButtonValueChanged(app, event)
           app.UpdateButton.Value = false;
           app.makeImage;
           app.updateDisplay;
            
        end

        % Value changed function: VolumeViewerButton
        function VolumeViewerButtonValueChanged(app, event)
            volumeViewer(app.im, 'ScaleFactors',[app.tm.xybin app.tm.xybin app.tm.zbin]);
            app.VolumeViewerButton.Value = false;

        end

        % Value changed function: ThresholdSlider
        function ThresholdSliderValueChanged(app, event)
            app.ThresholdEditField.Value = app.ThresholdSlider.Value;
            app.makeImage();
            app.updateDisplay();
        end

        % Value changing function: ThresholdSlider
        function ThresholdSliderValueChanging(app, event)
            app.ThresholdEditField.Value = event.Value;            
        end

        % Callback function: CreateAnalysisMaskUIFigure, DoneButton
        function UIFigureCloseRequest(app, event)
            delete(app)            
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            app.ThresholdEditField.Value = max(0, app.ThresholdEditField.Value);
            app.ThresholdEditField.Value = min(app.ThresholdSlider.Limits(end), app.ThresholdEditField.Value);
            app.ThresholdSlider.Value = app.ThresholdEditField.Value;
            app.makeImage();
            app.updateDisplay();
        end

        % Value changing function: cutoffSlider
        function cutoffSliderValueChanging(app, event)
            
            app.vs.Isovalue = event.Value;
            nz = max(1,ceil(event.Value*255));

            app.vs.Alphamap(1:nz) = 0;
            app.vs.Alphamap(nz:end) = linspace(0,1,length(app.vs.Alphamap(nz:end)));

        end

        % Value changed function: RendererDropDown
        function RendererDropDownValueChanged(app, event)
            value = app.RendererDropDown.Value;
            app.vs.Renderer = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CreateAnalysisMaskUIFigure = uifigure('Visible', 'off');
            app.CreateAnalysisMaskUIFigure.Name = 'Create Analysis Mask';
            app.CreateAnalysisMaskUIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.OrthoViewPanel = uipanel(app.CreateAnalysisMaskUIFigure);
            app.OrthoViewPanel.Title = 'Panel';
            app.VolumeViewerButton = uibutton(app.CreateAnalysisMaskUIFigure, 'state');
            app.VolumeViewerButton.ValueChangedFcn = createCallbackFcn(app, @VolumeViewerButtonValueChanged, true);
            app.VolumeViewerButton.Text = 'Volume Viewer';
            app.blurxyEditFieldLabel = uilabel(app.CreateAnalysisMaskUIFigure);
            app.blurxyEditFieldLabel.Text = 'blur x,y';
            app.blurxyEditField = uieditfield(app.CreateAnalysisMaskUIFigure, 'numeric');
            app.blurxyEditField.Limits = [0.01 10];
            app.blurxyEditField.ValueDisplayFormat = '%2.1g';
            app.blurxyEditField.ValueChangedFcn = createCallbackFcn(app, @UpdateButtonValueChanged, true);
            app.blurxyEditField.Value = 1;
            app.blurzEditFieldLabel = uilabel(app.CreateAnalysisMaskUIFigure);
            app.blurzEditFieldLabel.Text = 'blur z';
            app.blurzEditField = uieditfield(app.CreateAnalysisMaskUIFigure, 'numeric');
            app.blurzEditField.Limits = [0.01 10];
            app.blurzEditField.ValueDisplayFormat = '%2.1g';
            app.blurzEditField.ValueChangedFcn = createCallbackFcn(app, @UpdateButtonValueChanged, true);
            app.blurzEditField.Value = 1;
            app.dilateEditFieldLabel = uilabel(app.CreateAnalysisMaskUIFigure);
            app.dilateEditFieldLabel.Text = 'dilate';
            app.dilateEditField = uieditfield(app.CreateAnalysisMaskUIFigure, 'numeric');
            app.dilateEditField.Limits = [0 20];
            app.dilateEditField.RoundFractionalValues = 'on';
            app.dilateEditField.ValueDisplayFormat = '%d';
            app.dilateEditField.ValueChangedFcn = createCallbackFcn(app, @UpdateButtonValueChanged, true);
            app.dilateEditField.Value = 1;
            app.UpdateButton = uibutton(app.CreateAnalysisMaskUIFigure, 'state');
            app.UpdateButton.ValueChangedFcn = createCallbackFcn(app, @UpdateButtonValueChanged, true);
            app.UpdateButton.Text = 'Update';
            app.ThresholdSliderLabel = uilabel(app.CreateAnalysisMaskUIFigure);
            app.ThresholdSliderLabel.Text = 'Threshold';
            app.ThresholdSlider = uislider(app.CreateAnalysisMaskUIFigure);
            app.ThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ThresholdSliderValueChanged, true);
            app.ThresholdSlider.ValueChangingFcn = createCallbackFcn(app, @ThresholdSliderValueChanging, true);
            app.DoneButton = uibutton(app.CreateAnalysisMaskUIFigure, 'state');
            app.DoneButton.ValueChangedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.DoneButton.Text = 'Done';
            app.isosurfacePanel = uipanel(app.CreateAnalysisMaskUIFigure);
            app.isosurfacePanel.Title = 'Panel2';
            app.closeEditFieldLabel = uilabel(app.CreateAnalysisMaskUIFigure);
            app.closeEditFieldLabel.Text = 'close';
            app.closeEditField = uieditfield(app.CreateAnalysisMaskUIFigure, 'numeric');
            app.closeEditField.Limits = [0 200];
            app.closeEditField.RoundFractionalValues = 'on';
            app.closeEditField.ValueDisplayFormat = '%d';
            app.closeEditField.ValueChangedFcn = createCallbackFcn(app, @UpdateButtonValueChanged, true);
            app.closeEditField.Value = 1;
            app.thenLabel = uilabel(app.CreateAnalysisMaskUIFigure);
            app.thenLabel.Text = 'then->';
            app.thenLabel_2 = uilabel(app.CreateAnalysisMaskUIFigure);
            app.thenLabel_2.Text = 'then->';
            app.viewDropDownLabel = uilabel(app.CreateAnalysisMaskUIFigure);
            app.viewDropDownLabel.Text = 'view';
            app.viewDropDown = uidropdown(app.CreateAnalysisMaskUIFigure);
            app.viewDropDown.Items = {'Original', 'Blurred', 'Thresholded', 'Final', 'Mask'};
            app.viewDropDown.ValueChangedFcn = createCallbackFcn(app, @UpdateButtonValueChanged, true);
            app.viewDropDown.Value = 'Final';
            app.ThresholdEditField = uieditfield(app.CreateAnalysisMaskUIFigure, 'numeric');
            app.ThresholdEditField.Limits = [0 Inf];
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.ThresholdEditField.Value = 1;
            app.cutoffSliderLabel = uilabel(app.CreateAnalysisMaskUIFigure);
            app.cutoffSliderLabel.Text = 'cutoff';
            app.cutoffSlider = uislider(app.CreateAnalysisMaskUIFigure);
            app.cutoffSlider.Limits = [0 1];
            app.cutoffSlider.ValueChangingFcn = createCallbackFcn(app, @cutoffSliderValueChanging, true);
            app.cutoffSlider.Value = 0.01;
            app.RendererDropDownLabel = uilabel(app.CreateAnalysisMaskUIFigure);
            app.RendererDropDownLabel.Text = 'Renderer';
            app.RendererDropDown = uidropdown(app.CreateAnalysisMaskUIFigure);
            app.RendererDropDown.Items = {'VolumeRendering', 'MaximumIntensityProjection', 'Isosurface'};
            app.RendererDropDown.ValueChangedFcn = createCallbackFcn(app, @RendererDropDownValueChanged, true);
            app.RendererDropDown.Value = 'VolumeRendering';
            % Show the figure after all components are created
            app.CreateAnalysisMaskUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AnalysisMaskSelector(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CreateAnalysisMaskUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CreateAnalysisMaskUIFigure)
        end
    end
end