classdef RoiSelector3D < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SelectROIUIFigure       matlab.ui.Figure
        ClipnonroiCheckBox      matlab.ui.control.CheckBox
        roiLabel                matlab.ui.control.Label
        ImageTypeDropDown       matlab.ui.control.DropDown
        ImageTypeDropDownLabel  matlab.ui.control.Label
        MIPMEANButton           matlab.ui.control.StateButton
        MaxSlider               matlab.ui.control.Slider
        MaxSliderLabel          matlab.ui.control.Label
        MinSlider               matlab.ui.control.Slider
        MinLabel                matlab.ui.control.Label
        DoneButton              matlab.ui.control.StateButton
        ZYProj                  matlab.ui.control.UIAxes
        XZProj                  matlab.ui.control.UIAxes
        XYProj                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        rsi % Description
        xyroi = [];% Description
        xzroi = [];
        zyroi = [];
        beingDeleted = false; % Description
        redraw = true;
        volume;
        clipToRoi;
    end
    
    methods (Access = private)
        
        function updateDisplay (app)
            if (app.beingDeleted)
                return;
            end
            if (app.ClipnonroiCheckBox.Value ~= app.clipToRoi)
                app.clipToRoi = app.ClipnonroiCheckBox.Value;
                app.redraw = true;
            end
            if (app.redraw)
                d = uiprogressdlg(app.SelectROIUIFigure, 'Title','Please Wait','Message', 'Updating');
            else
                d = [];
            end

            xi = app.rsi.xedges >= min(app.rsi.roi.x) & app.rsi.xedges <= max(app.rsi.roi.x);
            yi = app.rsi.yedges >= min(app.rsi.roi.y) & app.rsi.yedges <= max(app.rsi.roi.y);
            zi = app.rsi.zedges >= min(app.rsi.roi.z) & app.rsi.zedges <= max(app.rsi.roi.z);
            
            if (~app.MIPMEANButton.Value) %default to MIP
                pfun = @(data,dim) squeeze(max(data,[],dim));
            else
                pfun = @(data,dim) squeeze(mean(data,dim));
            end
            
            if (app.redraw)
                if (app.clipToRoi)
                    pcolor(app.XYProj, app.rsi.xedges(xi), app.rsi.yedges(yi), pfun(app.volume(xi,yi,zi),3)'); shading(app.XYProj,'flat'); axis(app.XYProj, 'equal');
                    pcolor(app.XZProj, app.rsi.xedges(xi), app.rsi.zedges(zi), pfun(app.volume(xi,yi,zi),2)'); shading(app.XZProj,'flat'); axis(app.XZProj, 'equal');axis(app.XZProj, 'ij');
                    pcolor(app.ZYProj, app.rsi.zedges(zi), app.rsi.yedges(yi), pfun(app.volume(xi,yi,zi),1)); shading(app.ZYProj,'flat'); axis(app.ZYProj, 'equal');
                else
                    pcolor(app.XYProj, app.rsi.xedges, app.rsi.yedges, pfun(app.volume(:,:,zi),3)'); shading(app.XYProj,'flat'); axis(app.XYProj, 'equal');
                    pcolor(app.XZProj, app.rsi.xedges, app.rsi.zedges, pfun(app.volume(:,yi,:),2)'); shading(app.XZProj,'flat'); axis(app.XZProj, 'equal');axis(app.XZProj, 'ij');
                    pcolor(app.ZYProj, app.rsi.zedges, app.rsi.yedges, pfun(app.volume(xi,:,:),1)); shading(app.ZYProj,'flat'); axis(app.ZYProj, 'equal');
                end
                app.redraw = false;
            end
            if (~isempty(d)), d.Value = .33; end

            app.XZProj.Position([1 3]) = app.XYProj.Position([1 3]);
            app.ZYProj.Position([2 4]) = app.XYProj.Position([2 4]);
            
            maxv = max([max(pfun(app.volume(:,:,zi),3),[],'all'),max(pfun(app.volume(:,yi,:),2),[],'all'),max(pfun(app.volume(xi,:,:),1),[],'all')]);

            app.MinSlider.Limits = [0 maxv];
            app.MaxSlider.Limits = [0 maxv];

            app.XYProj.CLim = [app.MinSlider.Value app.MaxSlider.Value];
            app.XZProj.CLim = [app.MinSlider.Value app.MaxSlider.Value];
            app.ZYProj.CLim = [app.MinSlider.Value app.MaxSlider.Value];
            
            if (~isempty(d)), d.Value = .67; end
            app.makeRois();
            if (~isempty(d)), d.Value = 1; end
            drawnow;
            if (~isempty(d)), close(d); end
            app.roiLabel.Text = sprintf('roi x: %.2f, %.2f ; y: %.2f, %.2f ; z : %.2f %.2f', ...
                app.rsi.roi.x(1), app.rsi.roi.x(2), app.rsi.roi.y(1), app.rsi.roi.y(2), app.rsi.roi.z(1), app.rsi.roi.z(2));
            
        end
    end
    
    methods (Access = public)
        
        function roiUpdate(app,axs,r)
            

            if (app.beingDeleted)
                return;
            end
            app.rsi.roi.(axs{1}) = r(1) + [0 r(3)];
            app.rsi.roi.(axs{2}) = r(2) + [0 r(4)];
            
            app.rsi.roi = app.rsi.snapRoiToGrid(app.rsi.roi);

            app.rsiToRoi;

            app.updateDisplay();
            
        end
        function rsiToRoi(app)
            if (app.beingDeleted)
                return;
            end
            xp = [app.rsi.roi.x(1) diff(app.rsi.roi.x)];
            yp = [app.rsi.roi.y(1) diff(app.rsi.roi.y)];
            zp = [app.rsi.roi.z(1) diff(app.rsi.roi.z)];
            
            app.xyroi.Position = [xp(1) yp(1) xp(2) yp(2)];
            app.xzroi.Position = [xp(1) zp(1) xp(2) zp(2)];
            app.zyroi.Position = [zp(1) yp(1) zp(2) yp(2)];
        end

        function makeRois(app)
            if (app.beingDeleted)
                return;
            end
            try
                try 
                    app.xyroi.bringToFront();    
                catch
                    app.xyroi = images.roi.Rectangle('Parent', app.XYProj);
                    app.xyroi.addlistener('ROIMoved', @(r,evt) app.roiUpdate({'x','y'}, evt.CurrentPosition));
                end
    
                try 
                    app.xzroi.bringToFront();    
                catch
                    app.xzroi = images.roi.Rectangle('Parent', app.XZProj);
                    app.xzroi.addlistener('ROIMoved', @(r,evt) app.roiUpdate({'x','z'}, evt.CurrentPosition));
                end
    
                try 
                    app.zyroi.bringToFront();    
                catch
                    app.zyroi = images.roi.Rectangle('Parent', app.ZYProj);
                    app.zyroi.addlistener('ROIMoved', @(r,evt) app.roiUpdate({'z','y'}, evt.CurrentPosition));
                end
                 app.rsiToRoi();
            catch
                return;
            end
          
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, roi_selector_input)
            app.rsi = roi_selector_input;
            app.clipToRoi = false;
            if (iscell(app.rsi.volume))
                app.volume = app.rsi.volume{1};
                app.ImageTypeDropDown.Items = app.rsi.label;
                app.ImageTypeDropDown.ItemsData = 1:length(app.rsi.volume);
            else
                app.volume = app.rsi.volume;
                app.ImageTypeDropDown.Visible = false;
            end

            app.rsi.roi.x = [min(app.rsi.xedges) max(app.rsi.xedges)];
            app.rsi.roi.y = [min(app.rsi.yedges) max(app.rsi.yedges)];
            app.rsi.roi.z = [min(app.rsi.zedges) max(app.rsi.zedges)];

            w = diff(app.rsi.roi.x); %uses fact that roi is initialized to limits
            h = diff(app.rsi.roi.y);
            d = diff(app.rsi.roi.z);

            figwidth = app.SelectROIUIFigure.Position(3);
            figheight = app.SelectROIUIFigure.Position(4);

            xsc = figwidth / (w + d);
            ysc = figheight / (h + d);

            sc = min(xsc,ysc)*.95;

            w = sc*w;
            h = sc*h;
            d = sc*d;

            app.XYProj.Position = [5 figheight-h-5 w h];
            app.ZYProj.Position = [figwidth-d-5 figheight-h-5 d h];
            app.XZProj.Position = [5 5 w d];

            axis(app.XYProj, 'off')
            axis(app.XZProj, 'off')
            axis(app.ZYProj, 'off')

            disableDefaultInteractivity(app.XYProj);
            disableDefaultInteractivity(app.XZProj);
            disableDefaultInteractivity(app.ZYProj);


            app.MinSlider.Value = 0;
            app.MaxSlider.Value = max(app.MaxSlider.Limits);

            app.updateDisplay();

            app.MinSlider.Value = 0;
            app.MaxSlider.Value = max(app.MaxSlider.Limits);
            app.updateDisplay();

            app.rsiToRoi();


            %app.xyroi.addlistener('MovingROI', @(r,~) app.rsi.roi.x = r.Position(1) + [0 r.position(3)]);


        end

        % Callback function: DoneButton, SelectROIUIFigure
        function UIFigureCloseRequest(app, event)
            if (app.beingDeleted)
                return;
            end
            app.beingDeleted = true;
            app.rsi.finished = true;
            delete(app)
            
        end

        % Value changed function: MIPMEANButton
        function MIPMEANButtonValueChanged(app, event)
            app.redraw = true;
            app.updateDisplay();
        end

        % Value changed function: ClipnonroiCheckBox, MaxSlider, MinSlider
        function UpdateDisplayCallback(app, event)
            app.updateDisplay();
        end

        % Value changed function: ImageTypeDropDown
        function ImageTypeDropDownValueChanged(app, event)
            app.volume = app.rsi.volume{app.ImageTypeDropDown.Value};
            app.redraw = true;
            app.updateDisplay();
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SelectROIUIFigure = uifigure('Visible', 'off');
            app.SelectROIUIFigure.Name = 'Select ROI';
            app.SelectROIUIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.XYProj = uiaxes(app.SelectROIUIFigure);
            app.XZProj = uiaxes(app.SelectROIUIFigure);
            app.ZYProj = uiaxes(app.SelectROIUIFigure);
            app.DoneButton = uibutton(app.SelectROIUIFigure, 'state');
            app.DoneButton.ValueChangedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.DoneButton.Text = 'Done';
            app.MinLabel = uilabel(app.SelectROIUIFigure);
            app.MinLabel.Text = 'Min';
            app.MinSlider = uislider(app.SelectROIUIFigure);
            app.MinSlider.ValueChangedFcn = createCallbackFcn(app, @UpdateDisplayCallback, true);
            app.MaxSliderLabel = uilabel(app.SelectROIUIFigure);
            app.MaxSliderLabel.Text = 'Max';
            app.MaxSlider = uislider(app.SelectROIUIFigure);
            app.MaxSlider.ValueChangedFcn = createCallbackFcn(app, @UpdateDisplayCallback, true);
            app.MIPMEANButton = uibutton(app.SelectROIUIFigure, 'state');
            app.MIPMEANButton.ValueChangedFcn = createCallbackFcn(app, @MIPMEANButtonValueChanged, true);
            app.MIPMEANButton.Text = 'MIP/MEAN';
            app.ImageTypeDropDownLabel = uilabel(app.SelectROIUIFigure);
            app.ImageTypeDropDownLabel.Text = 'Image Type';
            app.ImageTypeDropDown = uidropdown(app.SelectROIUIFigure);
            app.ImageTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ImageTypeDropDownValueChanged, true);
            app.roiLabel = uilabel(app.SelectROIUIFigure);
            app.ClipnonroiCheckBox = uicheckbox(app.SelectROIUIFigure);
            app.ClipnonroiCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpdateDisplayCallback, true);
            app.ClipnonroiCheckBox.Text = 'Clip non-roi';
            % Show the figure after all components are created
            app.SelectROIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RoiSelector3D(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SelectROIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SelectROIUIFigure)
        end
    end
end