classdef TemplateCreator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CreateTemplateUIFigure        matlab.ui.Figure
        SetAnalysisMaskButton         matlab.ui.control.StateButton
        CreateSpacedVolumeCheckBox    matlab.ui.control.CheckBox
        WaitingLabel                  matlab.ui.control.Label
        DoneButton                    matlab.ui.control.StateButton
        MaxSlider                     matlab.ui.control.Slider
        MaxSliderLabel                matlab.ui.control.Label
        MinSlider                     matlab.ui.control.Slider
        MinSliderLabel                matlab.ui.control.Label
        SmoothingEditField            matlab.ui.control.NumericEditField
        SmoothingEditFieldLabel       matlab.ui.control.Label
        UpdateButton                  matlab.ui.control.StateButton
        SelectionsTextArea            matlab.ui.control.TextArea
        SelectionsTextAreaLabel       matlab.ui.control.Label
        methodDropDown                matlab.ui.control.DropDown
        methodDropDownLabel           matlab.ui.control.Label
        numberofframesEditField       matlab.ui.control.NumericEditField
        numberofframesEditFieldLabel  matlab.ui.control.Label
        SelectROIButton               matlab.ui.control.StateButton
        zbinEditField                 matlab.ui.control.NumericEditField
        zbinEditFieldLabel            matlab.ui.control.Label
        xybinEditField                matlab.ui.control.NumericEditField
        xybinEditFieldLabel           matlab.ui.control.Label
        VolumeViewerButton            matlab.ui.control.StateButton
        SmoothingSlider               matlab.ui.control.Slider
        SmoothingSliderLabel          matlab.ui.control.Label
        OrthoViewPanel                matlab.ui.container.Panel
    end

    
    properties (Access = private)
        tm % TemplateMaker
        inds
        inds_ordered
        fvp
        roi
        im
        osv
        dxy
        dz

        tmroi

    end
    
    methods (Access = private)
        
        function makeImage(app)
            d = uiprogressdlg(app.CreateTemplateUIFigure, 'Title','Please Wait','Message', 'Updating');
            app.dxy = app.xybinEditField.Value;
            app.dz = app.zbinEditField.Value;
            app.tm.setBinning(app.roi, app.dxy, app.dz);
            n = app.numberofframesEditField.Value;
            switch lower(app.methodDropDown.Value)
                case 'best'
                    ii = app.inds_ordered(1:n);
                case 'random'
                    ii = app.inds(randperm(length(app.inds), n));
                case 'even'
                    ii = app.inds(round(linspace(1,length(app.inds), n)));
                case 'even-best'
                    ii = round(linspace(1,length(app.inds), 4*n)); 
                    [~,I] = sort(app.fvp(ii), 'descend');
                    ii = app.inds(ii(I(1:n)));
            end
            app.im = app.tm.makeImSpline(ii, app.SmoothingSlider.Value*1e-2);
            app.MaxSlider.Limits = [min(app.im,[],'all') max(app.im,[],'all')];
            app.MinSlider.Limits = [min(app.im,[],'all') max(app.im,[],'all')];     
            [xx,yy,zz] = app.tm.fa.getBinCenters;
            app.tm.template.u = xx; 
            app.tm.template.v = yy;
            app.tm.template.w = zz;
            app.tm.template.F = app.im;
            app.tm.template.F_2D = max(app.im,[],3);
            app.tm.method = app.methodDropDown.Value;
            d.close();


        end
        function updateDisplay(app)
            try
                p = app.OrthoViewPanel.Position;
                app.OrthoViewPanel.delete();
                app.OrthoViewPanel = uipanel(app.CreateTemplateUIFigure);
                app.OrthoViewPanel.Position = p;            
            catch me
            end
            app.osv = orthosliceViewer(app.im, 'Parent',app.OrthoViewPanel, 'ScaleFactors', [app.dxy app.dxy app.dz], 'Colormap', jet(256));
           
            app.osv.DisplayRange = [app.MinSlider.Value app.MaxSlider.Value];
            app.osv.DisplayRangeInteraction = 'off';
            app.SelectionsTextArea.Value = app.tm.toText();
            [hXY,hYZ,hXZ] = getAxesHandles(app.osv);
            disableDefaultInteractivity(hXY);
            disableDefaultInteractivity(hYZ);
            disableDefaultInteractivity(hXZ);
        end

        
        function addVoi(app)
            app.vs.addVoi(app.voi);
            app.state = 'default';
            
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, template_maker)
            app.WaitingLabel.Visible = false;
            app.tm = template_maker;
            try 
                if (~isempty(app.tm.method))
                    app.methodDropDown.Value = app.tm.method;
                end
                if (~isempty(app.tm.selected_frames))
                    app.numberofframesEditField.Value = length(app.tm.selected_frames);
                end
                if (~isempty(app.tm.xybin))
                    app.xybinEditField.Value = app.tm.xybin;
                end
                if (~isempty(app.tm.zbin))
                    app.zbinEditField.Value = app.tm.zbin;
                end
                if (~isempty(app.tm.smooth_s))
                    app.SmoothingEditField.Value = app.tm.smooth_s*100;
                    app.SmoothingSlider.Value = app.tm.smooth_s*100;
                end
            catch
            end
            app.tm.finished = false;
            fvp = [app.tm.vra.metrics.fval_per_photon];
            app.inds = find(fvp > 0);
            app.fvp = fvp(app.inds);
            [~,I] = sort(fvp(app.inds),'descend');
            app.inds_ordered = app.inds(I);
            if (~isempty(app.tm.roi))
                app.roi = app.tm.roi;
            else
                app.roi = app.tm.vra.template_range;
            end
            app.makeImage();
            app.MaxSlider.Value = max(app.im,[],'all');
            app.MinSlider.Value = min(app.im, [], 'all');
            app.updateDisplay();

            app.tmroi = TemplateMaker(app.tm.vra);
            app.tmroi.setBinning(app.tm.vra.template_range, 2, 4);

        end

        % Callback function
        function SmoothingSliderValueChanged(app, event)
            value = app.SmoothingSlider.Value;
            
        end

        % Value changed function: UpdateButton
        function UpdateButtonValueChanged(app, event)
           app.UpdateButton.Value = false;
           app.makeImage;
           app.updateDisplay;
            
        end

        % Value changed function: SelectROIButton
        function SelectROIButtonValueChanged(app, event)

            
            app.WaitingLabel.Visible = true;
            drawnow;
            xe = app.tmroi.fa.xedges;
            ye = app.tmroi.fa.yedges;
            ze = app.tmroi.fa.zedges;
            %[xc,yc,zc] = app.tmroi.fa.getBinCenters();

            nbest = 4;
            im1 = app.tmroi.makeImSpline(app.inds_ordered(1:nbest), 1e-2);
            neven = 30;
            if (app.CreateSpacedVolumeCheckBox.Value)
                ii = app.inds(round(linspace(1,length(app.inds),neven)));
                im2 = app.tmroi.makeImSpline(ii, 1e-2);
            end
            app.WaitingLabel.Visible = false;
            app.SelectROIButton.Value = false;
            if (app.CreateSpacedVolumeCheckBox.Value)
                app.roi = getRoiFrom3DProjections(xe,ye,ze,{im1,im2},{'best', 'even'});
            else
                 app.roi = getRoiFrom3DProjections(xe,ye,ze,im1);
            end
            app.makeImage;
            app.updateDisplay;
            
        end

        % Value changed function: VolumeViewerButton
        function VolumeViewerButtonValueChanged(app, event)
            volumeViewer(app.im, 'ScaleFactors',[app.dxy app.dxy app.dz]);
            app.VolumeViewerButton.Value = false;

        end

        % Value changing function: SmoothingSlider
        function SmoothingSliderValueChanging(app, event)
            changingValue = event.Value;
            app.SmoothingEditField.Value = changingValue;
        end

        % Value changed function: SmoothingEditField
        function SmoothingEditFieldValueChanged(app, event)
            value = app.SmoothingEditField.Value;
            app.SmoothingSlider.Value = value;
        end

        % Value changed function: MaxSlider, MinSlider
        function MinMaxSliderValueChanged(app, event)
            app.MinSlider.Value = min(app.MaxSlider.Value*.999, app.MinSlider.Value);
            app.osv.DisplayRange = [app.MinSlider.Value app.MaxSlider.Value];
        end

        % Callback function
        function MaxMinSliderValueChanging(app, event)
            
        end

        % Value changing function: MinSlider
        function MinSliderValueChanging(app, event)
            app.MaxSlider.Value = max(app.MaxSlider.Value, event.Value*1.001);
            app.osv.DisplayRange = [event.Value app.MaxSlider.Value];
            
        end

        % Value changing function: MaxSlider
        function MaxSliderValueChanging(app, event)
            app.MinSlider.Value = min(event.Value*.999, app.MinSlider.Value);
            app.osv.DisplayRange = [app.MinSlider.Value event.Value];
        end

        % Callback function: CreateTemplateUIFigure, DoneButton
        function UIFigureCloseRequest(app, event)
            if (isempty(app.tm.analysis_mask))
                SetAnalysisMaskButtonValueChanged(app, event);
            end
            app.tm.finished = true;
            delete(app)
            
        end

        % Value changed function: SetAnalysisMaskButton
        function SetAnalysisMaskButtonValueChanged(app, event)
            app.SetAnalysisMaskButton.Value = false;
            asm = AnalysisMaskSelector(app.tm);
            waitfor(asm);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CreateTemplateUIFigure = uifigure('Visible', 'off');
            app.CreateTemplateUIFigure.Name = 'Create Template';
            app.CreateTemplateUIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.OrthoViewPanel = uipanel(app.CreateTemplateUIFigure);
            app.OrthoViewPanel.Title = 'Panel';
            app.SmoothingSliderLabel = uilabel(app.CreateTemplateUIFigure);
            app.SmoothingSliderLabel.Text = 'Smoothing';
            app.SmoothingSlider = uislider(app.CreateTemplateUIFigure);
            app.SmoothingSlider.Limits = [0 10];
            app.SmoothingSlider.ValueChangingFcn = createCallbackFcn(app, @SmoothingSliderValueChanging, true);
            app.SmoothingSlider.Value = 1;
            app.VolumeViewerButton = uibutton(app.CreateTemplateUIFigure, 'state');
            app.VolumeViewerButton.ValueChangedFcn = createCallbackFcn(app, @VolumeViewerButtonValueChanged, true);
            app.VolumeViewerButton.Text = 'Volume Viewer';
            app.xybinEditFieldLabel = uilabel(app.CreateTemplateUIFigure);
            app.xybinEditFieldLabel.Text = 'xybin';
            app.xybinEditField = uieditfield(app.CreateTemplateUIFigure, 'numeric');
            app.xybinEditField.Limits = [0.1 10];
            app.xybinEditField.ValueDisplayFormat = '%2.1g';
            app.xybinEditField.Value = 1;
            app.zbinEditFieldLabel = uilabel(app.CreateTemplateUIFigure);
            app.zbinEditFieldLabel.Text = 'z bin';
            app.zbinEditField = uieditfield(app.CreateTemplateUIFigure, 'numeric');
            app.zbinEditField.Limits = [0.1 10];
            app.zbinEditField.ValueDisplayFormat = '%2.1g';
            app.zbinEditField.Value = 2;
            app.SelectROIButton = uibutton(app.CreateTemplateUIFigure, 'state');
            app.SelectROIButton.ValueChangedFcn = createCallbackFcn(app, @SelectROIButtonValueChanged, true);
            app.SelectROIButton.Text = 'Select ROI';
            app.numberofframesEditFieldLabel = uilabel(app.CreateTemplateUIFigure);
            app.numberofframesEditFieldLabel.Text = 'number of frames';
            app.numberofframesEditField = uieditfield(app.CreateTemplateUIFigure, 'numeric');
            app.numberofframesEditField.Limits = [0 200];
            app.numberofframesEditField.RoundFractionalValues = 'on';
            app.numberofframesEditField.ValueDisplayFormat = '%d';
            app.numberofframesEditField.Value = 1;
            app.methodDropDownLabel = uilabel(app.CreateTemplateUIFigure);
            app.methodDropDownLabel.Text = 'method';
            app.methodDropDown = uidropdown(app.CreateTemplateUIFigure);
            app.methodDropDown.Items = {'Best', 'Even', 'Even-Best'};
            app.methodDropDown.Value = 'Best';
            app.SelectionsTextAreaLabel = uilabel(app.CreateTemplateUIFigure);
            app.SelectionsTextAreaLabel.Text = 'Selections';
            app.SelectionsTextArea = uitextarea(app.CreateTemplateUIFigure);
            app.UpdateButton = uibutton(app.CreateTemplateUIFigure, 'state');
            app.UpdateButton.ValueChangedFcn = createCallbackFcn(app, @UpdateButtonValueChanged, true);
            app.UpdateButton.Text = 'Update';
            app.SmoothingEditFieldLabel = uilabel(app.CreateTemplateUIFigure);
            app.SmoothingEditFieldLabel.Text = 'Smoothing';
            app.SmoothingEditField = uieditfield(app.CreateTemplateUIFigure, 'numeric');
            app.SmoothingEditField.Limits = [1e-06 100];
            app.SmoothingEditField.ValueChangedFcn = createCallbackFcn(app, @SmoothingEditFieldValueChanged, true);
            app.SmoothingEditField.Value = 1;
            app.MinSliderLabel = uilabel(app.CreateTemplateUIFigure);
            app.MinSliderLabel.Text = 'Min';
            app.MinSlider = uislider(app.CreateTemplateUIFigure);
            app.MinSlider.ValueChangedFcn = createCallbackFcn(app, @MinMaxSliderValueChanged, true);
            app.MinSlider.ValueChangingFcn = createCallbackFcn(app, @MinSliderValueChanging, true);
            app.MaxSliderLabel = uilabel(app.CreateTemplateUIFigure);
            app.MaxSliderLabel.Text = 'Max';
            app.MaxSlider = uislider(app.CreateTemplateUIFigure);
            app.MaxSlider.ValueChangedFcn = createCallbackFcn(app, @MinMaxSliderValueChanged, true);
            app.MaxSlider.ValueChangingFcn = createCallbackFcn(app, @MaxSliderValueChanging, true);
            app.DoneButton = uibutton(app.CreateTemplateUIFigure, 'state');
            app.DoneButton.ValueChangedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.DoneButton.Text = 'Done';
            app.WaitingLabel = uilabel(app.CreateTemplateUIFigure);
            app.WaitingLabel.Text = {'Creating Volumes'; 'for ROI display'};
            app.CreateSpacedVolumeCheckBox = uicheckbox(app.CreateTemplateUIFigure);
            app.CreateSpacedVolumeCheckBox.Text = 'Create Spaced Volume?';
            app.SetAnalysisMaskButton = uibutton(app.CreateTemplateUIFigure, 'state');
            app.SetAnalysisMaskButton.ValueChangedFcn = createCallbackFcn(app, @SetAnalysisMaskButtonValueChanged, true);
            app.SetAnalysisMaskButton.Text = 'Set Analysis Mask';
            % Show the figure after all components are created
            app.CreateTemplateUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TemplateCreator(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CreateTemplateUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CreateTemplateUIFigure)
        end
    end
end