classdef VOISelector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        DoneButton                    matlab.ui.control.Button
        AddConvexButton               matlab.ui.control.Button
        SaveVOIsetButton              matlab.ui.control.Button
        voisetnicknameEditField       matlab.ui.control.EditField
        voisetnicknameEditFieldLabel  matlab.ui.control.Label
        ChooseVOIfileButton           matlab.ui.control.Button
        voioutputEditField            matlab.ui.control.EditField
        voioutputEditFieldLabel       matlab.ui.control.Label
        AlwaysAutoCheckBox            matlab.ui.control.CheckBox
        ApplyVOItoSlicesCheckBox      matlab.ui.control.CheckBox
        autoradiusButton              matlab.ui.control.Button
        DeleteButton                  matlab.ui.control.Button
        AddSphereButton               matlab.ui.control.Button
        AddCylinderButton             matlab.ui.control.Button
        MinSlider                     matlab.ui.control.Slider
        MinSliderLabel                matlab.ui.control.Label
        MaxSlider                     matlab.ui.control.Slider
        MaxSliderLabel                matlab.ui.control.Label
        cutoffSlider                  matlab.ui.control.Slider
        ImageTypeDropDown             matlab.ui.control.DropDown
        ImageTypeDropDownLabel        matlab.ui.control.Label
        VolumePanel                   matlab.ui.container.Panel
        rmaxEditField                 matlab.ui.control.NumericEditField
        rmaxEditFieldLabel            matlab.ui.control.Label
        rminEditField                 matlab.ui.control.NumericEditField
        rminEditFieldLabel            matlab.ui.control.Label
        VOISelectionDropDown          matlab.ui.control.DropDown
        VOISelectionDropDownLabel     matlab.ui.control.Label
        radiusEditField               matlab.ui.control.NumericEditField
        radiusEditFieldLabel          matlab.ui.control.Label
        SelectButton                  matlab.ui.control.Button
        OrthoPanel                    matlab.ui.container.Panel
    end

    
    properties (Access = private)
        vs % VolumeSelector object
        volume % image to show
        labelim % labeled image to show
        osv %orthosliceviewer
        vso %volshow object
        state
        voi % voi being created
        umPerSlice
        scaleFactors
        cvPoints
    end
    
    methods (Access = private)
        
        function updateDisplay(app)

            try 
                sn = app.osv.SliceNumbers;
            catch
                sn = [];
            end
            try
                p = app.OrthoPanel.Position;
                app.OrthoPanel.delete();
                app.OrthoPanel = uipanel(app.UIFigure);
                app.OrthoPanel.Position = p;     
                   
            catch me 
                disp(me.getReport)
            end


            cm = jet(256);
            cm(1,:) = 1;

            if (app.ApplyVOItoSlicesCheckBox.Value)
                mask = double(app.labelim > 0);
            else
                mask = ones(size(app.volume));
            end
            app.osv = orthosliceViewer(app.volume.*mask, 'Parent',app.OrthoPanel, 'ScaleFactors', app.scaleFactors, 'Colormap', cm);
           if (~isempty(sn))
               app.osv.SliceNumbers = sn;
           end

            app.MinSlider.Limits = [min(app.volume,[],'all') max(app.volume,[],'all')];
            app.MaxSlider.Limits = [min(app.volume,[],'all') max(app.volume,[],'all')];
            app.MinSlider.Value = min(app.MinSlider.Limits);
            app.MaxSlider.Value = max(app.MaxSlider.Limits);
            app.osv.DisplayRange = [app.MinSlider.Value app.MaxSlider.Value];
            app.osv.DisplayRangeInteraction = 'off';
            [hXY,hYZ,hXZ] = getAxesHandles(app.osv);
            disableDefaultInteractivity(hXY);
            disableDefaultInteractivity(hYZ);
            disableDefaultInteractivity(hXZ);


            try
                app.vso.setVolume(app.labelim, app.volume);
            catch
                try
                    p = app.VolumePanel.Position;
                    app.VolumePanel.delete();
                    app.VolumePanel = uipanel(app.UIFigure);
                    app.VolumePanel.Position = p;  
    
                    app.vso = labelvolshow(app.labelim, app.volume, 'Parent',app.VolumePanel, ...
                                'BackgroundColor', 'w', 'ScaleFactors', app.scaleFactors,  'VolumeThreshold',app.cutoffSlider.Value,...
                                'CameraPosition', [0 2.6 -2.6], 'CameraUpVector', [0 -.3 -1]);
                catch
                end
            end            
        end
        
        
        function addVoi(app)
            app.vs.addVoi(app.voi);
            app.state = 'default';
            app.AddCylinderButton.Visible = true;
            app.AddSphereButton.Visible = true;
            app.AddConvexButton.Visible = true;
            app.SelectButton.Visible = false;  
            app.DoneButton.Visible = false;
            app.setVoiDropdown();
            app.VOISelectionDropDown.Value = max(app.VOISelectionDropDown.ItemsData);
            app.OrthoPanel.Title = '';
            app.setLabelIm;
        end
        function setVoiDropdown(app)
            app.VOISelectionDropDown.ItemsData = 0:length(app.vs.vois);
            for j = 1:length(app.VOISelectionDropDown.ItemsData)
                it{j} = num2str(j-1); %#ok<AGROW> 
            end
            it{1} = 'all';
            app.VOISelectionDropDown.Items = it;
        end


        function setLabelIm(app)
            if (app.VOISelectionDropDown.Value == 0)
                app.labelim = app.vs.makeLabelMap;
            else
                app.labelim = double(app.vs.getMask(app.VOISelectionDropDown.Value ));
            end
            if (app.ApplyVOItoSlicesCheckBox.Value)
                app.updateDisplay;
            else
                app.vso.setVolume(app.labelim, app.volume);
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, vs)
            app.vs = vs;

            dx = median(diff(app.vs.xaxis));
            dy = median(diff(app.vs.yaxis));
            dz = median(diff(app.vs.zaxis));

            app.umPerSlice = min([dx dy dz]);
            app.scaleFactors = [dy dx dz]/app.umPerSlice;

            if (iscell(app.vs.volume))
                app.volume = app.vs.volume{1};
                app.ImageTypeDropDown.Items = app.vs.label;
                app.ImageTypeDropDown.ItemsData = 1:length(app.vs.volume);
                app.ImageTypeDropDown.Value = 1;
            else
                app.volume = app.vs.volume;
                app.ImageTypeDropDown.Visible = false;
            end
            app.labelim = app.vs.makeLabelMap;
            app.updateDisplay;
            app.setVoiDropdown;
            app.VOISelectionDropDown.Value = 0;

            app.voioutputEditField.Value = app.vs.dstfile;


        end

        % Value changed function: VOISelectionDropDown
        function VOISelectionDropDownValueChanged(app, event)
            app.setLabelIm();
           
        end

        % Value changing function: MinSlider
        function MinSliderValueChanging(app, event)
            app.MaxSlider.Value = max(app.MaxSlider.Value, event.Value*1.001);
            app.osv.DisplayRange = [event.Value app.MaxSlider.Value];           
        end

        % Value changing function: MaxSlider
        function MaxSliderValueChanging(app, event)
            app.MinSlider.Value = min(event.Value*.999, app.MinSlider.Value);
            app.osv.DisplayRange = [app.MinSlider.Value event.Value];            
        end

        % Value changed function: MaxSlider
        function MinMaxSliderValueChanged(app, event)
            app.MinSlider.Value = min(app.MaxSlider.Value*.999, app.MinSlider.Value);
            app.osv.DisplayRange = [app.MinSlider.Value app.MaxSlider.Value];            
        end

        % Button pushed function: AddCylinderButton
        function AddCylinderButtonPushed(app, event)
            app.AddCylinderButton.Visible = false;
            app.AddSphereButton.Visible = false;
            app.AddConvexButton.Visible = false;
            app.SelectButton.Visible = true;
            app.state = 'addCylinder1';
            app.OrthoPanel.Title = 'Move crosshairs to first end of axis, then press Select';
            app.voi = CylinderVoi(app.vs.xaxis, app.vs.yaxis, app.vs.zaxis);
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            sn = app.osv.SliceNumbers;
            x = min(app.vs.xaxis) + (sn(2)-1)*app.umPerSlice;
            y = min(app.vs.yaxis) + (sn(1)-1)*app.umPerSlice;
            z = min(app.vs.zaxis) + (sn(3)-1)*app.umPerSlice;
            
            switch (lower(app.state))
                case 'addcylinder1'
                    app.voi.pt1 = [x y z];
                    app.state = 'addCylinder2';
                    app.OrthoPanel.Title = 'Move crosshairs to second end of axis, then press Select';
                case 'addcylinder2'
                    app.voi.pt2 = [x y z];
                    if (app.AlwaysAutoCheckBox.Value)
                        rng = sort([app.rminEditField.Value app.rmaxEditField.Value]);
                        [app.voi, app.radiusEditField.Value] = app.voi.autoSetRadius(app.volume, rng);
                    else
                        app.voi.radius = app.radiusEditField.Value;
                    end
                    app.addVoi;
                case 'addsphere'
                    app.voi.pt = [x y z];
                    if (app.AlwaysAutoCheckBox.Value)
                        rng = sort([app.rminEditField.Value app.rmaxEditField.Value]);
                        [app.voi, app.radiusEditField.Value] = app.voi.autoSetRadius(app.volume, rng);
                    else
                        app.voi.radius = app.radiusEditField.Value;
                    end
                    app.addVoi;       
                case 'addconvex'
                    app.cvPoints = [app.cvPoints; x y z];
                    try
                        [~,vol] = convhull(app.cvPoints);
                        if (vol > 0)
                            app.DoneButton.Visible = 'on';
                        end
                    catch
                    end
            end

        end

        % Button pushed function: AddSphereButton
        function AddSphereButtonPushed(app, event)
            app.AddCylinderButton.Visible = false;
            app.AddSphereButton.Visible = false;
            app.AddConvexButton.Visible = false;
            app.SelectButton.Visible = true;
            app.state = 'addSphere';
            app.OrthoPanel.Title = 'Move crosshairs to center of sphere, then press Select';
            app.voi = SphereVoi(app.vs.xaxis, app.vs.yaxis, app.vs.zaxis);
        end

        % Value changing function: cutoffSlider
        function cutoffSliderValueChanging(app, event)
            app.vso.VolumeThreshold = event.Value;
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            v = app.VOISelectionDropDown.Value;
            app.vs.deleteVoi(v);
            app.setVoiDropdown;
            app.VOISelectionDropDown.Value = min(v,max(app.VOISelectionDropDown.ItemsData));
            app.setLabelIm;
        end

        % Value changed function: ImageTypeDropDown
        function ImageTypeDropDownValueChanged(app, event)
            value = app.ImageTypeDropDown.Value;
            app.volume = app.vs.volume{value};
            app.updateDisplay;

        end

        % Button pushed function: autoradiusButton
        function autoradiusButtonPushed(app, event)
           if (app.VOISelectionDropDown.Value <= 0)
               return;
           end
           try
               rng = sort([app.rminEditField.Value app.rmaxEditField.Value]);
               [app.vs.vois{app.VOISelectionDropDown.Value}, app.radiusEditField.Value] = app.vs.vois{app.VOISelectionDropDown.Value}.autoSetRadius(app.volume, rng);
               app.setLabelIm;
           catch me%
                disp(me.getReport());
           end
               
        end

        % Value changed function: radiusEditField
        function radiusEditFieldValueChanged(app, event)
           if (app.VOISelectionDropDown.Value <= 0)
               return;
           end
           try
              app.vs.vois{app.VOISelectionDropDown.Value}.radius = app.radiusEditField.Value;
              app.setLabelIm;
           catch me%
                disp(me.getReport());
           end
        end

        % Value changed function: ApplyVOItoSlicesCheckBox
        function ApplyVOItoSlicesCheckBoxValueChanged(app, event)
            app.updateDisplay();
        end

        % Button pushed function: ChooseVOIfileButton
        function ChooseVOIfileButtonPushed(app, event)
            
            [file,path] = uiputfile('*.mat', 'select voi mat file', app.vs.dstfile);
            app.vs.dstfile = fullfile(path, file);
            app.voioutputEditField.Value = app.vs.dstfile;

        end

        % Button pushed function: SaveVOIsetButton
        function SaveVOIsetButtonPushed(app, event)
            app.vs.saveVoiSet(app.voisetnicknameEditField.Value);

        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            app.voi = app.voi.setPoints(app.cvPoints);
            app.cvPoints = zeros([0 3]);
            app.addVoi();
        end

        % Button pushed function: AddConvexButton
        function AddConvexButtonPushed(app, event)
            app.AddCylinderButton.Visible = false;
            app.AddSphereButton.Visible = false;
            app.AddConvexButton.Visible = false;
            app.SelectButton.Visible = true;
            app.DoneButton.Visible = false;
            app.OrthoPanel.Title = 'Move crosshairs to points surrounding the VOI, then press Select';
            app.voi = ConvHullVoi(app.vs.xaxis, app.vs.yaxis, app.vs.zaxis);
            app.state = 'addconvex';

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OrthoPanel = uipanel(app.UIFigure);
            app.OrthoPanel.Title = 'OrthoPanel';
            app.SelectButton = uibutton(app.UIFigure, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Visible = 'off';
            app.SelectButton.Text = 'Select';
            app.radiusEditFieldLabel = uilabel(app.UIFigure);
            app.radiusEditFieldLabel.Text = 'radius';
            app.radiusEditField = uieditfield(app.UIFigure, 'numeric');
            app.radiusEditField.ValueChangedFcn = createCallbackFcn(app, @radiusEditFieldValueChanged, true);
            app.radiusEditField.Value = 3;
            app.VOISelectionDropDownLabel = uilabel(app.UIFigure);
            app.VOISelectionDropDownLabel.Text = 'VOI Selection';
            app.VOISelectionDropDown = uidropdown(app.UIFigure);
            app.VOISelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @VOISelectionDropDownValueChanged, true);
            app.rminEditFieldLabel = uilabel(app.UIFigure);
            app.rminEditFieldLabel.Text = 'rmin';
            app.rminEditField = uieditfield(app.UIFigure, 'numeric');
            app.rminEditField.Value = 1;
            app.rmaxEditFieldLabel = uilabel(app.UIFigure);
            app.rmaxEditFieldLabel.Text = 'rmax';
            app.rmaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.rmaxEditField.Value = 10;
            app.VolumePanel = uipanel(app.UIFigure);
            app.VolumePanel.Title = 'VolumePanel';
            app.ImageTypeDropDownLabel = uilabel(app.UIFigure);
            app.ImageTypeDropDownLabel.Text = 'Image Type';
            app.ImageTypeDropDown = uidropdown(app.UIFigure);
            app.ImageTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ImageTypeDropDownValueChanged, true);
            app.cutoffSlider = uislider(app.UIFigure);
            app.cutoffSlider.Limits = [0 1];
            app.cutoffSlider.ValueChangingFcn = createCallbackFcn(app, @cutoffSliderValueChanging, true);
            app.cutoffSlider.Value = 0.5;
            app.MaxSliderLabel = uilabel(app.UIFigure);
            app.MaxSliderLabel.Text = 'Max';
            app.MaxSlider = uislider(app.UIFigure);
            app.MaxSlider.ValueChangedFcn = createCallbackFcn(app, @MinMaxSliderValueChanged, true);
            app.MaxSlider.ValueChangingFcn = createCallbackFcn(app, @MaxSliderValueChanging, true);
            app.MinSliderLabel = uilabel(app.UIFigure);
            app.MinSliderLabel.Text = 'Min';
            app.MinSlider = uislider(app.UIFigure);
            app.MinSlider.ValueChangingFcn = createCallbackFcn(app, @MinSliderValueChanging, true);
            app.AddCylinderButton = uibutton(app.UIFigure, 'push');
            app.AddCylinderButton.ButtonPushedFcn = createCallbackFcn(app, @AddCylinderButtonPushed, true);
            app.AddCylinderButton.Text = 'Add Cylinder';
            app.AddSphereButton = uibutton(app.UIFigure, 'push');
            app.AddSphereButton.ButtonPushedFcn = createCallbackFcn(app, @AddSphereButtonPushed, true);
            app.AddSphereButton.Text = 'Add Sphere';
            app.DeleteButton = uibutton(app.UIFigure, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.autoradiusButton = uibutton(app.UIFigure, 'push');
            app.autoradiusButton.ButtonPushedFcn = createCallbackFcn(app, @autoradiusButtonPushed, true);
            app.autoradiusButton.Text = 'auto radius';
            app.ApplyVOItoSlicesCheckBox = uicheckbox(app.UIFigure);
            app.ApplyVOItoSlicesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ApplyVOItoSlicesCheckBoxValueChanged, true);
            app.ApplyVOItoSlicesCheckBox.Text = 'Apply VOI to Slices';
            app.AlwaysAutoCheckBox = uicheckbox(app.UIFigure);
            app.AlwaysAutoCheckBox.Text = 'Always Auto';
            app.AlwaysAutoCheckBox.Value = true;
            app.voioutputEditFieldLabel = uilabel(app.UIFigure);
            app.voioutputEditFieldLabel.Text = 'voi output';
            app.voioutputEditField = uieditfield(app.UIFigure, 'text');
            app.ChooseVOIfileButton = uibutton(app.UIFigure, 'push');
            app.ChooseVOIfileButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseVOIfileButtonPushed, true);
            app.ChooseVOIfileButton.Text = 'Choose VOI file';
            app.voisetnicknameEditFieldLabel = uilabel(app.UIFigure);
            app.voisetnicknameEditFieldLabel.Text = 'voi set nickname';
            app.voisetnicknameEditField = uieditfield(app.UIFigure, 'text');
            app.SaveVOIsetButton = uibutton(app.UIFigure, 'push');
            app.SaveVOIsetButton.ButtonPushedFcn = createCallbackFcn(app, @SaveVOIsetButtonPushed, true);
            app.SaveVOIsetButton.Text = 'Save VOI set';
            app.AddConvexButton = uibutton(app.UIFigure, 'push');
            app.AddConvexButton.ButtonPushedFcn = createCallbackFcn(app, @AddConvexButtonPushed, true);
            app.AddConvexButton.Text = 'Add Convex';
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Visible = 'off';
            app.DoneButton.Text = 'Done';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VOISelector(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end