classdef VolumeRotator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RotateVolumeUIFigure        matlab.ui.Figure
        MIPMEANButton               matlab.ui.control.StateButton
        MaxSlider                   matlab.ui.control.Slider
        MaxSliderLabel              matlab.ui.control.Label
        MinSlider                   matlab.ui.control.Slider
        MinSliderLabel              matlab.ui.control.Label
        UpdateButton                matlab.ui.control.Button
        DoneButton                  matlab.ui.control.Button
        TextLabel                   matlab.ui.control.Label
        ZaxisXYplaneEditField       matlab.ui.control.NumericEditField
        ZaxisXYplaneEditFieldLabel  matlab.ui.control.Label
        YaxisXZplaneEditField       matlab.ui.control.NumericEditField
        YaxisXZplaneEditFieldLabel  matlab.ui.control.Label
        XaxisYZplaneEditField       matlab.ui.control.NumericEditField
        XaxisYZplaneEditFieldLabel  matlab.ui.control.Label
        ZYProj                      matlab.ui.control.UIAxes
        XZProj                      matlab.ui.control.UIAxes
        XYProj                      matlab.ui.control.UIAxes
    end


    properties (Access = private)
        vra % VRAligner object
        im % (rotated) 3D image to display
        inds % registered frame inds
        fvp % registration metrics
        inds_ordered % registered frame inds sorted by metrics, high to low
        redraw = true; % for update display
        beingDeleted = false; % for app control
    end

    methods (Access = private)

        function updateDisplay(app)
            if (app.beingDeleted)
                return;
            end
            
            if (~app.MIPMEANButton.Value) %default to MIP
                pfun = @(data,dim) squeeze(max(data,[],dim));
            else
                pfun = @(data,dim) squeeze(mean(data,dim));
            end

            if (app.redraw)
                app.makeImage;
                pcolor(app.XYProj,pfun(app.im,3)'); shading(app.XYProj,'flat'); axis(app.XYProj,'tight','xy');
                pcolor(app.XZProj,pfun(app.im,2)'); shading(app.XZProj,'flat'); axis(app.XZProj,'tight','ij');
                pcolor(app.ZYProj,pfun(app.im,1)); shading(app.ZYProj,'flat'); axis(app.ZYProj,'tight','xy');
                app.redraw = false;
            end
            
            maxv = max([max(pfun(app.im,3),[],'all'),max(pfun(app.im,2),[],'all'),max(pfun(app.im,1),[],'all')]);
            
            app.MinSlider.Limits = [0 maxv];
            app.MaxSlider.Limits = [0 maxv];
            
            app.XYProj.CLim = [app.MinSlider.Value app.MaxSlider.Value];
            app.XZProj.CLim = [app.MinSlider.Value app.MaxSlider.Value];
            app.ZYProj.CLim = [app.MinSlider.Value app.MaxSlider.Value];
            
            drawnow;
        end
        
        
        function makeImage(app)
            ii = app.inds_ordered(1); % just use best registered frame for now
            [ct,dw] = app.vra.binFrame(ii);
            im = ct./dw; %#ok<*ADPROP> 
            im(dw==0) = 0;
            im = imgaussfilt3(im,1);
            app.im = im;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, vra)
            app.vra = vra;
            
            fvp = [app.vra.metrics.fval_per_photon]; %#ok<*ADPROPLC> 
            app.inds = find(fvp > 0);
            app.fvp = fvp(app.inds);
            [~,I] = sort(fvp(app.inds),'descend');
            app.inds_ordered = app.inds(I);
            
            app.XaxisYZplaneEditField.Value = vra.additional_rotation(1);
            app.YaxisXZplaneEditField.Value = vra.additional_rotation(2);
            app.ZaxisXYplaneEditField.Value = vra.additional_rotation(3);
            
            set(app.XYProj,'XTick',[],'YTick',[]);
            set(app.XZProj,'XTick',[],'YTick',[]);
            set(app.ZYProj,'XTick',[],'YTick',[]);

            disableDefaultInteractivity(app.XYProj);
            disableDefaultInteractivity(app.XZProj);
            disableDefaultInteractivity(app.ZYProj);
            
            app.makeImage;

            app.MinSlider.Limits = prctile(app.im(app.im>0),[0 100]);
            app.MinSlider.Value = prctile(app.im(app.im>0),50);
            app.MaxSlider.Limits = prctile(app.im(app.im>0),[0 100]);
            app.MaxSlider.Value = prctile(app.im(app.im>0),99);

            app.updateDisplay;
        end

        % Value changed function: XaxisYZplaneEditField
        function XaxisYZplaneEditFieldValueChanged(app, event)
            app.vra.additional_rotation(1) = app.XaxisYZplaneEditField.Value;
            app.redraw = true;
        end

        % Value changed function: YaxisXZplaneEditField
        function YaxisXZplaneEditFieldValueChanged(app, event)
            app.vra.additional_rotation(2) = app.YaxisXZplaneEditField.Value;
            app.redraw = true;
        end

        % Value changed function: ZaxisXYplaneEditField
        function ZaxisXYplaneEditFieldValueChanged(app, event)
            app.vra.additional_rotation(3) = app.ZaxisXYplaneEditField.Value;
            app.redraw = true;
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            app.updateDisplay;
        end

        % Value changed function: MIPMEANButton
        function MIPMEANButtonValueChanged(app, event)
            app.redraw = true;
            app.updateDisplay;
        end

        % Value changed function: MaxSlider, MinSlider
        function MinMaxSliderValueChanged(app, event)
            app.updateDisplay;
        end

        % Close request function: RotateVolumeUIFigure
        function RotateVolumeUIFigureCloseRequest(app, event)
            if (app.beingDeleted)
                return;
            end
            app.beingDeleted = true;
            delete(app)
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            if (app.beingDeleted)
                return;
            end
            rotAngle = [app.XaxisYZplaneEditField.Value app.YaxisXZplaneEditField.Value app.ZaxisXYplaneEditField.Value];
            assignin('base','rotation_angle_deg',rotAngle);
            app.beingDeleted = true;
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RotateVolumeUIFigure = uifigure('Visible', 'off');
            app.RotateVolumeUIFigure.Name = 'Rotate Volume';
            app.RotateVolumeUIFigure.CloseRequestFcn = createCallbackFcn(app, @RotateVolumeUIFigureCloseRequest, true);
            app.XYProj = uiaxes(app.RotateVolumeUIFigure);
            xlabel(app.XYProj, 'X')
            ylabel(app.XYProj, 'Y')
            app.XZProj = uiaxes(app.RotateVolumeUIFigure);
            xlabel(app.XZProj, 'X')
            ylabel(app.XZProj, 'Z')
            app.ZYProj = uiaxes(app.RotateVolumeUIFigure);
            xlabel(app.ZYProj, 'Z')
            ylabel(app.ZYProj, 'Y')
            app.XaxisYZplaneEditFieldLabel = uilabel(app.RotateVolumeUIFigure);
            app.XaxisYZplaneEditFieldLabel.Text = 'X axis (YZ plane)';
            app.XaxisYZplaneEditField = uieditfield(app.RotateVolumeUIFigure, 'numeric');
            app.XaxisYZplaneEditField.ValueChangedFcn = createCallbackFcn(app, @XaxisYZplaneEditFieldValueChanged, true);
            app.YaxisXZplaneEditFieldLabel = uilabel(app.RotateVolumeUIFigure);
            app.YaxisXZplaneEditFieldLabel.Text = 'Y axis (XZ plane)';
            app.YaxisXZplaneEditField = uieditfield(app.RotateVolumeUIFigure, 'numeric');
            app.YaxisXZplaneEditField.ValueChangedFcn = createCallbackFcn(app, @YaxisXZplaneEditFieldValueChanged, true);
            app.ZaxisXYplaneEditFieldLabel = uilabel(app.RotateVolumeUIFigure);
            app.ZaxisXYplaneEditFieldLabel.Text = 'Z axis (XY plane)';
            app.ZaxisXYplaneEditField = uieditfield(app.RotateVolumeUIFigure, 'numeric');
            app.ZaxisXYplaneEditField.ValueChangedFcn = createCallbackFcn(app, @ZaxisXYplaneEditFieldValueChanged, true);
            app.TextLabel = uilabel(app.RotateVolumeUIFigure);
            app.TextLabel.Text = {'Rotation angle (degree)'; '+ve = counterclock-wise'};
            app.DoneButton = uibutton(app.RotateVolumeUIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.UpdateButton = uibutton(app.RotateVolumeUIFigure, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.MinSliderLabel = uilabel(app.RotateVolumeUIFigure);
            app.MinSliderLabel.Text = 'Min';
            app.MinSlider = uislider(app.RotateVolumeUIFigure);
            app.MinSlider.ValueChangedFcn = createCallbackFcn(app, @MinMaxSliderValueChanged, true);
            app.MaxSliderLabel = uilabel(app.RotateVolumeUIFigure);
            app.MaxSliderLabel.Text = 'Max';
            app.MaxSlider = uislider(app.RotateVolumeUIFigure);
            app.MaxSlider.ValueChangedFcn = createCallbackFcn(app, @MinMaxSliderValueChanged, true);
            app.MIPMEANButton = uibutton(app.RotateVolumeUIFigure, 'state');
            app.MIPMEANButton.ValueChangedFcn = createCallbackFcn(app, @MIPMEANButtonValueChanged, true);
            app.MIPMEANButton.Text = 'MIP/MEAN';
            % Show the figure after all components are created
            app.RotateVolumeUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VolumeRotator(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RotateVolumeUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RotateVolumeUIFigure)
        end
    end
end